classdef matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Toolbar      matlab.ui.container.Toolbar
        PushTool     matlab.ui.container.toolbar.PushTool
        PushTool2    matlab.ui.container.toolbar.PushTool
        XAxisSlider  matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        one          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function n = crown_glass(app, lambda)
            % Convert to microns
            x = lambda / 1000;
            % Sellmeier coefficients
            a = [1.03961212, 0.231792344, 1.01146945];
            b = [0.00600069867, 0.0200179144, 103.560653];
            % Build up formula for refractive index
            y = zeros(size(x));
            for k = 1:length(a)
                y = y + (a(k) * x.^2) ./ (x.^2 - b(k));
            end
            n = sqrt(1 + y);
        end

        function [R,G,B,colour_str] = colours_from_f(app, f)
            if f < 405
                R = NaN; G = NaN; B = NaN; colour_str = 'Infra Red';
            elseif (f>=405) && ( f < 480 )
                R = 1; G = (f-405)./75 .* 127/255; B = 0; colour_str = 'Red';
            elseif (f>=480) && ( f < 510 )
                R = 1; G = 127/255 + 127/255 .* (f-480)./30; B = 0; colour_str = 'Orange';
            elseif (f>=510) && ( f < 530 )
                R = (530-f)./20; G = 1; B = 0; colour_str = 'Yellow';
            elseif (f>=530) && ( f < 600 )
                R = 0; G = 1; B = (f-530)./70; colour_str = 'Green';
            elseif (f>=600) && ( f < 620 )
                R = 0; G = (620-f)./20; B = 1; colour_str = 'Cyan';
            elseif (f>=620) && ( f < 680 )
                R = (f-620)./60 .* 127/255; G = 0; B = 1; colour_str = 'Blue';
            elseif (f>=680) && ( f <= 790 )
                R = 127/255; G = 0; B = 1; colour_str = 'Violet';
            else
                R = NaN; G = NaN; B = NaN; colour_str = 'Ultra Violet';
            end
        end
        
        % empirical formula for refractive index of water over visible range
        n = @(f) sqrt(1 + (1.731 - 0.261 * (f / 1e15).^2).^(-1/2));
        
        % frequency range of visible light in THz
        f_visible = linspace(405e12,790e12,385);
        
        n_water = n(f_visible);
        
        % Get RGB colors for each frequency
        R = zeros(size(f_visible));
        G = zeros(size(f_visible));
        B = zeros(size(f_visible));
        for i = 1:length(f_visible)
            [R(i), G(i), B(i), ~] = colours_from_f(f_visible(i)/1e12);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            lambda = 400:800;                    % Wavelength range
            n = app.crown_glass(lambda);        % Call internal function
            plot(app.one, lambda, n, 'LineWidth', 2);
        end

        % Value changed function: XAxisSlider
        function XAxisSliderValueChanged(app, event)

        end

        % Value changing function: XAxisSlider
        function XAxisSliderValueChanging(app, event)
            xmax = round(event.Value);  % Slider value becomes new max x-limit
            app.one.XLim = [400 xmax];
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            plot(app.one, lambda, n, 'LineWidth', 2);
            app.XAxisSlider = 800;
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.one = uiaxes(app.UIFigure);
            title(app.one, 'Dispersion of Crown Glass')
            xlabel(app.one, 'Wavelength/nm')
            ylabel(app.one, 'Refractive Index')
            zlabel(app.one, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.XAxisSlider = uislider(app.UIFigure);
            app.XAxisSlider.Limits = [450 800];
            app.XAxisSlider.ValueChangedFcn = createCallbackFcn(app, @XAxisSliderValueChanged, true);
            app.XAxisSlider.ValueChangingFcn = createCallbackFcn(app, @XAxisSliderValueChanging, true);
            app.XAxisSlider.Step = 1;
            app.XAxisSlider.Value = 800;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end